classdef BurglarAlarmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SettingsPanel                  matlab.ui.container.Panel
        FacialrecognitionsettingsButton  matlab.ui.control.Button
        WarningLabel                   matlab.ui.control.Label
        AlarmToggleButton              matlab.ui.control.Button
        AlarmModeToggle                matlab.ui.container.ButtonGroup
        DeactivateButton               matlab.ui.control.ToggleButton
        ActivateButton                 matlab.ui.control.ToggleButton
        AlarmTriggeredList             matlab.ui.control.ListBox
        BurglarAlarmActivationsListBoxLabel  matlab.ui.control.Label
        PINPanel                       matlab.ui.container.Panel
        countdownLabel                 matlab.ui.control.Label
        SuccessLabel                   matlab.ui.control.Label
        ENTERPINEditField              matlab.ui.control.NumericEditField
        ENTERPINEditFieldLabel         matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
        AccessibiltyFunctionsLabel     matlab.ui.control.Label
        EnterTriggerButton             matlab.ui.control.Button
        DisarmPINField                 matlab.ui.control.NumericEditField
        IFTRIGGEREDENTERPINTOENDALARMLabel  matlab.ui.control.Label
        IdlePanel                      matlab.ui.container.Panel
        TimeWarningLabel               matlab.ui.control.Label
        ACTIVATEALARMButton            matlab.ui.control.Button
        FontSizeSpinner                matlab.ui.control.Spinner
        FontSizeSpinnerLabel           matlab.ui.control.Label
        EnterSizeButton                matlab.ui.control.StateButton
        ClockLabel                     matlab.ui.control.Label
        GreggsSafeSecuritySystemLabel  matlab.ui.control.Label
        FacialPanel                    matlab.ui.container.Panel
        IsCorrectFace                  matlab.ui.control.CheckBox
        FacialRecognitionButton        matlab.ui.control.Button
        CameraAxes                     matlab.ui.control.UIAxes
        AlarmStatusLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        clockTimer % Description

        serialObj      % Serial object for Arduino
        serialTimer    % Timer to poll Arduino
        facialTimer    % Timer to switch back to activated state
        countdownValue double = 60

        cam  % Define webcam object as a property
        previewTimer  % Define the timer object as a property
        AlarmLogTimes = {} % Description
    end
    
    methods (Access = private)        

        function results = updateClock(app)
            currentTime = datestr(now, 'dd-mmm-yyyy HH:MM:SS');
            app.ClockLabel.Text = currentTime;
        end
        
        % Update function
        function updateCountdown(app)
            app.countdownValue = app.countdownValue - 1;
            app.countdownLabel.Text = sprintf('Time left: %d', app.countdownValue);
    
            if app.countdownValue <= 0
                stop(app.facialTimer);
                delete(app.facialTimer);
                switchPanel(app, "FacialPanel"); % Call your panel-switch logic
            end
        end

        
        function checkSerial(app)
            try
                % Only check if data is available
                if app.serialObj.NumBytesAvailable > 0
                    % Read the incoming line

                    msg = readline(app.serialObj);
                    msg = strtrim(msg);  % Remove whitespace and newline
                    
                    % Check for known messages
                    switch msg
                        case "ALARM_TRIGGERED"
                            app.AlarmStatusLabel.Text = "Alarm has been triggered!";
                            app.EnterTriggerButton.Enable = "on";
                            app.DisarmPINField.Enable = "on";

                            timestamp = datestr(now, 'yyyy-mm-dd HH:MM:SS');
                            app.AlarmLogTimes{end+1} = timestamp;                        
                            % Update ListBox or UITable
                            app.AlarmTriggeredList.Items = app.AlarmLogTimes;  % if using ListBox
                            
                            app.AlarmStatusLabel.BackgroundColor = "red";
                            app.AlarmStatusLabel.FontColor = "black";
                            app.AlarmStatusLabel.FontSize = 30;
                        
                        case "ALARM_ACTIVATED"
                            app.AlarmStatusLabel.Text = "Alarm has been activated!";
                            app.EnterTriggerButton.Enable = "off";
                            app.DisarmPINField.Enable = "off";

                            app.AlarmStatusLabel.BackgroundColor = "black";
                            app.AlarmStatusLabel.FontColor = "white";
                            app.AlarmStatusLabel.FontSize = 20;

                        case "ALARM_DEACTIVATED"
                            app.AlarmStatusLabel.Text = "Alarm has been deactivated!";
                            app.EnterTriggerButton.Enable = "off";
                            app.DisarmPINField.Enable = "off";

                            app.AlarmStatusLabel.BackgroundColor = "white";
                            app.AlarmStatusLabel.FontColor = "black";
                            app.AlarmStatusLabel.FontSize = 20;

                        otherwise
                            % Optional: display unknown message
                            disp("Unknown serial message: " + msg);
                    end
                end
            catch ME
                % Handle serial read errors gracefully
                disp("Serial read error: " + ME.message);
            end
        end
        
        function updateCameraFrame(app)
            if isvalid(app.cam)
                img = snapshot(app.cam);
                image(app.CameraAxes, img);
                app.CameraAxes.Visible = 'off'; % remove axes ticks
                drawnow limitrate;
            end
        end
        
        % General function to switch panels
        function switchPanel(app, panelName)
            % List all panel names in the app (replace with actual panel names in your app)
            panelList = {'IdlePanel', 'FacialPanel', 'PINPanel', 'SettingsPanel'};
            
            % Loop through each panel and hide it
            for i = 1:length(panelList)
                if strcmp(panelList{i}, panelName)
                    % Show the panel that matches the name passed
                    app.(panelList{i}).Visible = 'on';
                else
                    % Hide the other panels
                    app.(panelList{i}).Visible = 'off';
                end
            end

            if strcmp(panelName, "PINPanel") 
                % Initial countdown value
                app.countdownValue = 30;
            
                % Create timer
                app.facialTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 1, ...
                    'TasksToExecute', app.countdownValue, ...
                    'TimerFcn', @(~,~) updateCountdown(app));
            
                % Start timer
                start(app.facialTimer);

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IdlePanel.Visible = false;
            app.FacialPanel.Visible = false;
            app.PINPanel.Visible = false;
            app.SettingsPanel.Visible = true;
            
            app.clockTimer = timer( ...
    'ExecutionMode', 'fixedRate', ...
    'Period', 1, ...
        'TimerFcn', @(~,~) updateClock(app));
start(app.clockTimer);
            
            try
                app.serialObj = serialport("COM4", 9600);  % Replace with your COM port
                configureTerminator(app.serialObj, "LF");
        
                % Timer to poll serial data
                app.serialTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 1, ...
                    'TimerFcn', @(~,~) checkSerial(app));
                start(app.serialTimer);
            catch err
                app.AlarmStatusLabel.Text = "Can't read Arduino..." + err.message;
            end
           
            % Connect to the default camera
            app.cam = webcam;
        
            % Optional: configure resolution
            % app.cam.Resolution = '640x480';
        
            % Start the preview in the UIAxes
            app.previewTimer = timer( ...
                'ExecutionMode', 'fixedSpacing', ...
                'Period', 0.1, ...
                'TimerFcn', @(~,~) updateCameraFrame(app));
        
            start(app.previewTimer);

           
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
        
            pinCode = app.ENTERPINEditField.Value;  % Get PIN from user input
            try
                
                % Check if serial object exists and is valid
                if isempty(app.serialObj) || ~isvalid(app.serialObj)
                    app.SuccessLabel.Text = "Serial not connected.";
                    return;
                end
            
                % Send PIN code to Arduino                
                flush(app.serialObj);
                writeline(app.serialObj, string(pinCode));
                pause(1);  % Wait for response
            
                % Read and handle response
                if app.serialObj.NumBytesAvailable > 0
                    response = readline(app.serialObj);
                    response = strip(response);
            
                    if strcmp(response, "ACCESS GRANTED")
                        app.SuccessLabel.Text = "Access Granted!";
                        pause(1);
                        switchPanel(app, "SettingsPanel");
                    elseif strcmp(response, "ACCESS DENIED")
                        app.SuccessLabel.Text = "Incorrect PIN.";
                    else
                        app.SuccessLabel.Text = "Unexpected Response: " + response;
                    end
                else
                    app.SuccessLabel.Text = "No response from Arduino.";
                end
            
                %clear s  % Not needed since we're using app.serialObj now
            catch err
                app.SuccessLabel.Text = "Error: " + err.message;
            end
        end

        % Value changed function: EnterSizeButton
        function EnterSizeButtonValueChanged(app, event)
            value = app.FontSizeSpinner.Value;
            
            app.SuccessLabel.FontSize = value;
        end

        % Button pushed function: FacialRecognitionButton
        function FacialRecognitionButtonPushed(app, event)
            try
                isCorrectFace = app.IsCorrectFace.Value;  % Get checkbox or logical input
        
                % Use the existing serial object
                if isempty(app.serialObj) || ~isvalid(app.serialObj)
                    app.SuccessLabel.Text = "Serial not connected.";
                    return;
                end
        
                % Send result to Arduino
                if isCorrectFace
                    flush(app.serialObj);
                    writeline(app.serialObj, "FACE_OK");  
                    pause(1);
                    switchPanel(app, 'PINPanel');
                else
                    writeline(app.serialObj, "FACE_NOTOK");
                end
        
                app.SuccessLabel.Text = "Facial data sent.";
        
            catch err
                app.SuccessLabel.Text = "Error: " + err.message;
            end
        end

        % Button pushed function: ACTIVATEALARMButton
        function ACTIVATEALARMButtonPushed(app, event)
            % Use the existing serial object
                if isempty(app.serialObj) || ~isvalid(app.serialObj)
                    app.TimeWarningLabel.Text = "Serial not connected.";
                    return;
                end

                flush(app.serialObj);
                writeline(app.serialObj, "2");  
                app.TimeWarningLabel.Text = "You have 10 seconds to leave the room, otherwise the alarm will be triggered.";
                app.TimeWarningLabel.Visible = 'on';               
                pause(10);
                app.TimeWarningLabel.Visible = 'off'; 
                switchPanel(app, "FacialPanel");
        end

        % Button pushed function: AlarmToggleButton
        function AlarmToggleButtonPushed(app, event)
            if isempty(app.serialObj) || ~isvalid(app.serialObj)
                app.StatusLabel.Text = "❌ Serial not connected.";
                return;
            end
    
            selection = app.AlarmModeToggle.SelectedObject.Text;
    
            flush(app.serialObj);

            if strcmp(selection, "Activate")
                writeline(app.serialObj, "1"); 
                app.WarningLabel.Text = "You have 10 seconds to leave.";
                pause(10);
                app.WarningLabel.Text = "";
                switchPanel(app, "FacialPanel");
            elseif strcmp(selection, "Deactivate")
                writeline(app.serialObj, "2"); 
                app.WarningLabel.Text = "Deactivating...";
                pause(1);
                app.WarningLabel.Text = "";
                switchPanel(app, "IdlePanel");
            end
        end

        % Button pushed function: EnterTriggerButton
        function EnterTriggerButtonPushed(app, event)
            pinCode = app.DisarmPINField.Value;
        
            try
                if isempty(app.serialObj) || ~isvalid(app.serialObj)
                    app.StatusLabel.Text = "❌ Serial not connected.";
                    return;
                end
        
                flush(app.serialObj);  % Clear previous input
                writeline(app.serialObj, string(pinCode));  % Send PIN to Arduino


                pause(1);  % Give Arduino time to respond
        
            catch err
                app.StatusLabel.Text = "❌ Error: " + err.message;
            end
        end

        % Button pushed function: FacialrecognitionsettingsButton
        function FacialrecognitionsettingsButtonPushed(app, event)
            streamingFaceRecognition(8, 0.5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AlarmStatusLabel = uilabel(app.UIFigure);
            app.AlarmStatusLabel.Text = 'Please Restart the Arduino';
            app.FacialPanel = uipanel(app.UIFigure);
            app.FacialPanel.Title = 'Facial Recognition Check';
            app.FacialPanel.Visible = 'off';
            app.CameraAxes = uiaxes(app.FacialPanel);
            title(app.CameraAxes, 'Title')
            xlabel(app.CameraAxes, 'X')
            ylabel(app.CameraAxes, 'Y')
            zlabel(app.CameraAxes, 'Z')
            app.FacialRecognitionButton = uibutton(app.FacialPanel, 'push');
            app.FacialRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @FacialRecognitionButtonPushed, true);
            app.FacialRecognitionButton.Text = '"Facial Recognition"';
            app.IsCorrectFace = uicheckbox(app.FacialPanel);
            app.GreggsSafeSecuritySystemLabel = uilabel(app.UIFigure);
            app.GreggsSafeSecuritySystemLabel.Text = 'Greggs Safe Security System';
            app.ClockLabel = uilabel(app.UIFigure);
            app.ClockLabel.Text = 'Imagine Date and Time Here';
            app.EnterSizeButton = uibutton(app.UIFigure, 'state');
            app.EnterSizeButton.ValueChangedFcn = createCallbackFcn(app, @EnterSizeButtonValueChanged, true);
            app.EnterSizeButton.Text = 'Enter Size';
            app.FontSizeSpinnerLabel = uilabel(app.UIFigure);
            app.FontSizeSpinnerLabel.Text = 'Font Size';
            app.FontSizeSpinner = uispinner(app.UIFigure);
            app.FontSizeSpinner.Limits = [20 64];
            app.FontSizeSpinner.RoundFractionalValues = 'on';
            app.FontSizeSpinner.Placeholder = 'Enter Font Size';
            app.FontSizeSpinner.Value = 20;
            app.IdlePanel = uipanel(app.UIFigure);
            app.IdlePanel.Title = 'Alarm is Deactivated - IDLE STATE';
            app.ACTIVATEALARMButton = uibutton(app.IdlePanel, 'push');
            app.ACTIVATEALARMButton.ButtonPushedFcn = createCallbackFcn(app, @ACTIVATEALARMButtonPushed, true);
            app.ACTIVATEALARMButton.Text = 'ACTIVATE ALARM';
            app.TimeWarningLabel = uilabel(app.IdlePanel);
            app.TimeWarningLabel.Visible = 'off';
            app.TimeWarningLabel.Text = 'You have 10 seconds to leave the room, otherwise the alarm will be triggered.';
            app.IFTRIGGEREDENTERPINTOENDALARMLabel = uilabel(app.UIFigure);
            app.IFTRIGGEREDENTERPINTOENDALARMLabel.Text = 'IF TRIGGERED, ENTER PIN TO END ALARM';
            app.DisarmPINField = uieditfield(app.UIFigure, 'numeric');
            app.EnterTriggerButton = uibutton(app.UIFigure, 'push');
            app.EnterTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @EnterTriggerButtonPushed, true);
            app.EnterTriggerButton.Text = 'Enter';
            app.AccessibiltyFunctionsLabel = uilabel(app.UIFigure);
            app.AccessibiltyFunctionsLabel.Text = 'Accessibilty Functions';
            app.PINPanel = uipanel(app.UIFigure);
            app.PINPanel.Title = 'Entering PIN';
            app.EnterButton = uibutton(app.PINPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ENTERPINEditFieldLabel = uilabel(app.PINPanel);
            app.ENTERPINEditFieldLabel.Text = 'ENTER PIN';
            app.ENTERPINEditField = uieditfield(app.PINPanel, 'numeric');
            app.SuccessLabel = uilabel(app.PINPanel);
            app.SuccessLabel.Text = 'Success!';
            app.countdownLabel = uilabel(app.PINPanel);
            app.countdownLabel.Text = 'Time Left:';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Panel3';
            app.BurglarAlarmActivationsListBoxLabel = uilabel(app.SettingsPanel);
            app.BurglarAlarmActivationsListBoxLabel.Text = 'Burglar Alarm Activations';
            app.AlarmTriggeredList = uilistbox(app.SettingsPanel);
            app.AlarmTriggeredList.Items = {'- - Log Start - -'};
            app.AlarmTriggeredList.Value = {};
            app.AlarmModeToggle = uibuttongroup(app.SettingsPanel);
            app.AlarmModeToggle.Title = 'Activate / Deactivate Alarm';
            app.ActivateButton = uitogglebutton(app.AlarmModeToggle);
            app.ActivateButton.Text = 'Activate';
            app.ActivateButton.Value = true;
            app.DeactivateButton = uitogglebutton(app.AlarmModeToggle);
            app.DeactivateButton.Text = 'Deactivate';
            app.AlarmToggleButton = uibutton(app.SettingsPanel, 'push');
            app.AlarmToggleButton.ButtonPushedFcn = createCallbackFcn(app, @AlarmToggleButtonPushed, true);
            app.AlarmToggleButton.Text = 'Enter';
            app.WarningLabel = uilabel(app.SettingsPanel);
            app.WarningLabel.Text = '';
            app.FacialrecognitionsettingsButton = uibutton(app.SettingsPanel, 'push');
            app.FacialrecognitionsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @FacialrecognitionsettingsButtonPushed, true);
            app.FacialrecognitionsettingsButton.Text = 'Facial recognition settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurglarAlarmApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end